classdef pid_controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UpdateSetpointButton         matlab.ui.control.Button
        CommunicationsLabel          matlab.ui.control.Label
        SetpointAdjustmentLabel      matlab.ui.control.Label
        TemperatureEditField         matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel    matlab.ui.control.Label
        SelectCOMPortDropDown        matlab.ui.control.DropDown
        SelectCOMPortDropDownLabel   matlab.ui.control.Label
        ConnectButton                matlab.ui.control.Button
        StartReadButton              matlab.ui.control.Button
        PIDParameterAdjustmentLabel  matlab.ui.control.Label
        SetParametersButton          matlab.ui.control.Button
        KdEditField                  matlab.ui.control.NumericEditField
        KdEditFieldLabel             matlab.ui.control.Label
        KiEditField                  matlab.ui.control.NumericEditField
        KiEditFieldLabel             matlab.ui.control.Label
        KpEditField                  matlab.ui.control.NumericEditField
        KpEditFieldLabel             matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Comms
        port;  %serial port object
        reading = false;
        portName;
        baudRate = 9600;
        
        %Plotting
        readTimer;
        plotData;
        readTimerStarted; % indicator
        
        %Misc
        GAIN_UPDATE = 'g';
        SETPOINT_UPDATE = 's';
        READY = 'r';
        
    end
   
    
    methods (Access = private)
        
        function TimerFcn(app,~,~)
            % Generate a random number
            %randomNumbers = randi([0, 1], [1, n])
            % Update YData in plot
            %app.plotData.YData = randomNumbers;   
%             try
                write(app.port,app.READY,"int8");
                readData = read(app.port,1,"int32");
                if(~size(readData,1))
                    return
                end
                temp = readData / 1e3;
                disp(readData);
                app.plotData.YData = circshift(app.plotData.YData,-1)
                app.plotData.YData(end) = temp;                
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Create timer object
            app.readTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', 0.1, ...                     % Period is 10s ms
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.TimerFcn);      % Specify callback function
            
            % Initial plot is all zeros
            app.plotData = plot(app.UIAxes,0:60,zeros(1,61));
            
            app.UIAxes.XLim = [0 60]; %utilize 60 data points
            app.UIAxes.YLim = [-10 50]; %temp range (0-65 deg C)
            
            % Fill out / refresh serial port selector
            available = serialportlist("available");
            disp(available)
            app.SelectCOMPortDropDown.Items = available;
            
            % Set default for application serial port
            app.portName = app.SelectCOMPortDropDown.Value;
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            %TODO: test
            %Send values to MSP
            write(app.port,app.GAIN_UPDATE,"int8");
            write(app.port, app.KpEditField.Value, "int16");
            write(app.port, app.KiEditField.Value, "int16");
            write(app.port, app.KdEditField.Value, "int16");
        end

        % Button pushed function: StartReadButton
        function StartReadButtonPushed(app, event)
            %Start or stop timer that polls data
%             app.readTimer = timer(...
%                 'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
%                 'Period', 0.1, ...                     % Period is 0.1 second
%                 'BusyMode', 'queue',...              % Queue timer callbacks when busy
%                 'TimerFcn', @app.TimerFcn);      % Specify callback function
            
%             if(~isvalid(app.readTimer))
%                 % Create timer object if it has been deleted
%                 app.readTimer = timer(...
%                     'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
%                     'Period', 0.5, ...                     % Period is 0.1 second
%                     'BusyMode', 'queue',...              % Queue timer callbacks when busy
%                     'TimerFcn', @app.TimerFcn);      % Specify callback function
%             
%             end
            
            if(~isvalid(app.readTimer))
                % Create timer object if it has been deleted
                app.readTimer = timer(...
                    'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                    'Period', 0.5, ...                     % Period is 0.1 second
                    'BusyMode', 'queue',...              % Queue timer callbacks when busy
                    'TimerFcn', @app.TimerFcn);      % Specify callback function           
            end
            if strcmp(app.readTimer.Running, 'off')
               %start timer
              app.StartReadButton.Text = "Stop Read";
               disp("Start read timer")
               start(app.readTimer);

            else
                %stop timer
                app.StartReadButton.Text = "Start Read";
               disp("Stop read timer")
                stop(app.readTimer);

            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % TODO: deal with invalid object check
            if(isvalid(app.readTimer))
                stop(app.readTimer);
            end
            delete(app.readTimer);
            delete(app.port);
            delete(app);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
            if(strcmp(app.ConnectButton.Text,"Disconnect"))
                
                
                % Update read button text if necessary
                if(strcmp(app.readTimer.Running,'on'))
                    app.StartReadButton.Text = "Start Read";
                end
                
                % Stop timer
                stop(app.readTimer);
                delete(app.readTimer);
                

                %delete com port
                delete(app.port);
                
                %indicate success
                f = msgbox("Serial port: "+app.portName+" disconnected.");
                
                %update ui
                app.StartReadButton.Enable = false;
                app.SetParametersButton.Enable = false;
                app.UpdateSetpointButton.Enable = false;
                app.ConnectButton.Text = "Connect";
                
                %reset plot
                app.plotData.YData = zeros(1,61);
                
                return;
            end
            
            % Fill out / refresh serial port selector
            available = serialportlist("available")
            disp(available)
            app.SelectCOMPortDropDown.Items = available;
            
            %search for port to see if it is available
            found = false;
            for i=1:length(available)
                if(strcmp(app.portName,available(i)))
                    found = true;
                    break
                end
            end
            
            if(found)
                app.port = serialport(app.portName,app.baudRate);
                f = msgbox("Serial port: "+app.portName+" connected successfully.");
                app.StartReadButton.Enable = true;
                app.SetParametersButton.Enable = true;
                app.UpdateSetpointButton.Enable = true;
                app.ConnectButton.Text = "Disconnect";
            else
                %display message
                f = msgbox("Serial port: "+app.portName+" not found.");
                app.StartReadButton.Enable = false;
                app.SetParametersButton.Enable = false;
                app.UpdateSetpointButton.Enable = false;
                app.ConnectButton.Text = "Connect";
            end
        end

        % Callback function
        function TestButtonPushed(app, event)
%             DEPRECATED
%             Simulate sending duty cycle percentage value
%             write(app.port,app.KiEditField.Value,"int8");

        end

        % Value changed function: SelectCOMPortDropDown
        function SelectCOMPortDropDownValueChanged(app, event)
            app.portName = app.SelectCOMPortDropDown.Value
        end

        % Button pushed function: UpdateSetpointButton
        function UpdateSetpointButtonPushed(app, event)
            %TODO: update setpoint (test)
            write(app.port,app.SETPOINT_UPDATE,"int8");
            write(app.port,app.TemperatureEditField.Value,"int16"); %TODO: send negative number??
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature v. Time')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Temperature (deg C)')
            zlabel(app.UIAxes, 'Z')
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.Value = 10;
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditField.Value = 1;
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditField.Value = 1;
            app.SetParametersButton = uibutton(app.UIFigure, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Enable = 'off';
            app.SetParametersButton.Text = 'Set Parameters';
            app.PIDParameterAdjustmentLabel = uilabel(app.UIFigure);
            app.PIDParameterAdjustmentLabel.Text = 'PID Parameter Adjustment';
            app.StartReadButton = uibutton(app.UIFigure, 'push');
            app.StartReadButton.ButtonPushedFcn = createCallbackFcn(app, @StartReadButtonPushed, true);
            app.StartReadButton.Enable = 'off';
            app.StartReadButton.Text = 'Start Read';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SelectCOMPortDropDownLabel = uilabel(app.UIFigure);
            app.SelectCOMPortDropDownLabel.Text = 'Select COM Port';
            app.SelectCOMPortDropDown = uidropdown(app.UIFigure);
            app.SelectCOMPortDropDown.Items = {};
            app.SelectCOMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCOMPortDropDownValueChanged, true);
            app.SelectCOMPortDropDown.Value = {};
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField.Value = 30;
            app.SetpointAdjustmentLabel = uilabel(app.UIFigure);
            app.SetpointAdjustmentLabel.Text = 'Setpoint Adjustment';
            app.CommunicationsLabel = uilabel(app.UIFigure);
            app.CommunicationsLabel.Text = 'Communications';
            app.UpdateSetpointButton = uibutton(app.UIFigure, 'push');
            app.UpdateSetpointButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateSetpointButtonPushed, true);
            app.UpdateSetpointButton.Enable = 'off';
            app.UpdateSetpointButton.Text = 'Update Setpoint';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pid_controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end